classdef Scope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        CH1KnobLabel   matlab.ui.control.Label
        CH1Knob        matlab.ui.control.Knob
        CH2KnobLabel   matlab.ui.control.Label
        CH2Knob        matlab.ui.control.Knob
        TimeKnobLabel  matlab.ui.control.Label
        TimeKnob       matlab.ui.control.Knob
        CH1Label       matlab.ui.control.Label
        VLabel         matlab.ui.control.Label
        CH2Label       matlab.ui.control.Label
        VLabel_2       matlab.ui.control.Label
        TLabel         matlab.ui.control.Label
        mLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
         s = serial('COM1','BaudRate',115200)
         p                      % Plot var
         pi = 3.141516;
         t = 0:199;
         fs = 4000;
         ch_a_data;
         ch_b_data;
    end
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s);
            disp(data)
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s.BytesAvailableFcnCount = 40;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @readSerial;
            app.ch_a_data = sin(2*app.pi*1000*app.t);         % For test
            app.ch_b_data = cos(2*app.pi*2000*app.t);
            app.p = plot(app.UIAxes, app.t, app.ch_a_data, app.t, app.ch_b_data);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.CH1KnobLabel = uilabel(app.UIFigure);
            app.CH1KnobLabel.Text = 'CH1';
            app.CH1Knob = uiknob(app.UIFigure, 'continuous');
            app.CH2KnobLabel = uilabel(app.UIFigure);
            app.CH2KnobLabel.Text = 'CH2';
            app.CH2Knob = uiknob(app.UIFigure, 'continuous');
            app.TimeKnobLabel = uilabel(app.UIFigure);
            app.TimeKnobLabel.Text = 'Time';
            app.TimeKnob = uiknob(app.UIFigure, 'continuous');
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'CH1';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '1.00V';
            app.CH2Label = uilabel(app.UIFigure);
            app.CH2Label.Text = 'CH2';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = '2.50V';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '500m';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scope

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end